classdef GUI_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        LeftWheelSteeringAngleGauge    matlab.ui.control.SemicircularGauge
        LeftWheelSteeringAngleGaugeLabel  matlab.ui.control.Label
        StopDataStreamingButton        matlab.ui.control.Button
        VehicleSpeedMPHEditField       matlab.ui.control.NumericEditField
        VehicleSpeedMPHEditFieldLabel  matlab.ui.control.Label
        StartDataStreamingButton       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        ThrottleGauge                  matlab.ui.control.LinearGauge
        ThrottleGaugeLabel             matlab.ui.control.Label
        RightWheelSteeringAngleGauge   matlab.ui.control.SemicircularGauge
        RightWheelSteeringAngleGaugeLabel  matlab.ui.control.Label
        RFWheelRPMGauge                matlab.ui.control.Gauge
        RFWheelRPMLabel                matlab.ui.control.Label
        LFWheelRPMGauge                matlab.ui.control.Gauge
        LFWheelRPMLabel                matlab.ui.control.Label
        LRWheelRPMGauge                matlab.ui.control.Gauge
        LRWheelRPMGaugeLabel           matlab.ui.control.Label
        RRWheelRPMGauge                matlab.ui.control.Gauge
        RRWheelRPMGaugeLabel           matlab.ui.control.Label
        GraphsTab                      matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        WheelSpeedTab                  matlab.ui.container.Tab
        WheelSpeedGraph                matlab.ui.control.UIAxes
        VehicleSpeedTab                matlab.ui.container.Tab
        CellVoltageTab                 matlab.ui.container.Tab
        SetupTab                       matlab.ui.container.Tab
        GPSFixLamp                     matlab.ui.control.Lamp
        GPSFixLampLabel                matlab.ui.control.Label
        SSHCarNameEditField            matlab.ui.control.NumericEditField
        SSHCarNameEditFieldLabel       matlab.ui.control.Label
        SSHPortNumberEditField         matlab.ui.control.NumericEditField
        SSHPortNumberEditFieldLabel    matlab.ui.control.Label
        SSHPasswordEditField           matlab.ui.control.EditField
        SSHPasswordEditFieldLabel      matlab.ui.control.Label
        SSHAddressEditField            matlab.ui.control.EditField
        SSHAddressEditFieldLabel       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StopDataStreamingButton
        function StopDataStreamingButtonPushed(app, event)
            global EndDataCollection;
            EndDataCollection = true;
            app.VehicleSpeedMPHEditField.Value = 50;
            app.GPSFixLamp.Color = [1 0 0];
        end

        % Button pushed function: StartDataStreamingButton
        function StartDataStreamingButtonPushed(app, event)
             %While app running
             global EndDataCollection;
             EndDataCollection = false;
             fileName = "TODO.csv";
             recentEOF = 0;
             data = {};          

             if EndDataCollection == true
                 app.GPSFixLamp.Color = [0 0 1];
                 app.ThrottleGauge.ScaleColors = [0 1 0];
                 app.ThrottleGauge.ScaleColorLimits = [0 ThrottlePosition];
             end
            
             while EndDataCollection ~= true
                % Get data from SSH
                fileID = fopen(fileName, 'r');
                if fileID == -1
                    continue
                end

                % Initialize variables
                fseek(fileID, -1, 'eof');
                pos = ftell(fileID);
                currentEOF = pos;
                line_count = 0;

                while pos > 0 && (line_count < min((currentEOF - recentEOF), 100))
                    fseek(fileID, pos, 'bof');
                    if fread(fileID, 1, 'char') == newline
                        line_count = line_count + 1;
                    end
                    pos = pos - 1;
                end

                line = fgetl(fileID);
                while ischar(line)
                    data{end + 1} = str2double(line);
                    line = fgetl(fileID);
                end
                fclose(fileID);
                recentEOF = currentEOF;


                % Variable Assignment
                data = {};
                ThrottlePosition = 37;
                SteeringPWM = ;
                ThrottlePWM = ;
                LFWheelRPM = ;
                RFWheelRPM = ;
                LRWheelRPM = ;
                RRWheelRPM = ;
                Cell1Voltage = ;
                Cell2Voltage = ;
                Cell3Voltage = ;
                Cell4Voltage = ;
                Cell5Voltage = ;
                Cell6Voltage = ;
                RollAngle = ;
                PitchAngle = ;
                YawAngle = ;
                GPSFix = ;


                % Math
                TotalVoltage = sum([Cell1Voltage Cell2Voltage Cell3Voltage Cell4Voltage Cell5Voltage Cell6Voltage]);
                SteeringAngle = ;
                ThrottlePosition = (ThrottlePWM-1500)/500;

                % Plotting


                % 
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'autumn');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.RRWheelRPMGaugeLabel = uilabel(app.HomeTab);
            app.RRWheelRPMGaugeLabel.Text = 'RR Wheel RPM';
            app.RRWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.LRWheelRPMGaugeLabel = uilabel(app.HomeTab);
            app.LRWheelRPMGaugeLabel.Text = 'LR Wheel RPM';
            app.LRWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.LFWheelRPMLabel = uilabel(app.HomeTab);
            app.LFWheelRPMLabel.Text = 'LF Wheel RPM';
            app.LFWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.RFWheelRPMLabel = uilabel(app.HomeTab);
            app.RFWheelRPMLabel.Text = 'RF Wheel RPM';
            app.RFWheelRPMGauge = uigauge(app.HomeTab, 'circular');
            app.RightWheelSteeringAngleGaugeLabel = uilabel(app.HomeTab);
            app.RightWheelSteeringAngleGaugeLabel.Text = 'Right Wheel Steering Angle';
            app.RightWheelSteeringAngleGauge = uigauge(app.HomeTab, 'semicircular');
            app.RightWheelSteeringAngleGauge.Limits = [-45 45];
            app.ThrottleGaugeLabel = uilabel(app.HomeTab);
            app.ThrottleGaugeLabel.Text = 'Throttle';
            app.ThrottleGauge = uigauge(app.HomeTab, 'linear');
            app.ThrottleGauge.Limits = [-100 100];
            app.ThrottleGauge.MajorTicks = [-100 -50 0 50 100];
            app.ThrottleGauge.MajorTickLabels = {'-100%', '-50%', '0%', '50%', '100%'};
            app.UITable = uitable(app.HomeTab);
            app.UITable.ColumnName = {'Cell'; 'Voltage'};
            app.UITable.RowName = {'Cell 1, Cell 2, Cell 3, Cell 4, Cell 5, Cell 6'};
            app.StartDataStreamingButton = uibutton(app.HomeTab, 'push');
            app.StartDataStreamingButton.ButtonPushedFcn = createCallbackFcn(app, @StartDataStreamingButtonPushed, true);
            app.StartDataStreamingButton.Text = 'Start Data Streaming';
            app.VehicleSpeedMPHEditFieldLabel = uilabel(app.HomeTab);
            app.VehicleSpeedMPHEditFieldLabel.Text = 'Vehicle Speed (MPH)';
            app.VehicleSpeedMPHEditField = uieditfield(app.HomeTab, 'numeric');
            app.VehicleSpeedMPHEditField.Editable = 'off';
            app.StopDataStreamingButton = uibutton(app.HomeTab, 'push');
            app.StopDataStreamingButton.ButtonPushedFcn = createCallbackFcn(app, @StopDataStreamingButtonPushed, true);
            app.StopDataStreamingButton.Text = 'Stop Data Streaming';
            app.LeftWheelSteeringAngleGaugeLabel = uilabel(app.HomeTab);
            app.LeftWheelSteeringAngleGaugeLabel.Text = 'Left Wheel Steering Angle';
            app.LeftWheelSteeringAngleGauge = uigauge(app.HomeTab, 'semicircular');
            app.LeftWheelSteeringAngleGauge.Limits = [-45 45];
            app.GraphsTab = uitab(app.TabGroup);
            app.GraphsTab.Title = 'Graphs';
            app.TabGroup2 = uitabgroup(app.GraphsTab);
            app.WheelSpeedTab = uitab(app.TabGroup2);
            app.WheelSpeedTab.Title = 'Wheel Speed';
            app.WheelSpeedGraph = uiaxes(app.WheelSpeedTab);
            title(app.WheelSpeedGraph, 'Wheel Speed vs Time')
            xlabel(app.WheelSpeedGraph, 'dt')
            ylabel(app.WheelSpeedGraph, 'RPM')
            zlabel(app.WheelSpeedGraph, 'Z')
            app.VehicleSpeedTab = uitab(app.TabGroup2);
            app.VehicleSpeedTab.Title = 'Vehicle Speed';
            app.CellVoltageTab = uitab(app.TabGroup2);
            app.CellVoltageTab.Title = 'Cell Voltage';
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.SSHAddressEditFieldLabel = uilabel(app.SetupTab);
            app.SSHAddressEditFieldLabel.Text = 'SSH Address';
            app.SSHAddressEditField = uieditfield(app.SetupTab, 'text');
            app.SSHAddressEditField.Value = 'rcCar';
            app.SSHPasswordEditFieldLabel = uilabel(app.SetupTab);
            app.SSHPasswordEditFieldLabel.Text = 'SSH Password';
            app.SSHPasswordEditField = uieditfield(app.SetupTab, 'text');
            app.SSHPasswordEditField.Value = 'admin';
            app.SSHPortNumberEditFieldLabel = uilabel(app.SetupTab);
            app.SSHPortNumberEditFieldLabel.Text = 'SSH Port Number';
            app.SSHPortNumberEditField = uieditfield(app.SetupTab, 'numeric');
            app.SSHCarNameEditFieldLabel = uilabel(app.SetupTab);
            app.SSHCarNameEditFieldLabel.Text = 'SSH Car Name';
            app.SSHCarNameEditField = uieditfield(app.SetupTab, 'numeric');
            app.GPSFixLampLabel = uilabel(app.SetupTab);
            app.GPSFixLampLabel.Text = 'GPS Fix';
            app.GPSFixLamp = uilamp(app.SetupTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end